classdef CHAT_analyzer_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CHAT_analyzer                   matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadChATimageMenu               matlab.ui.container.Menu
        LoaddendritesimageMenu          matlab.ui.container.Menu
        LoadsurfacesMenu                matlab.ui.container.Menu
        SavesurfacesMenu                matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        AutodetectpeaksButton           matlab.ui.control.Button
        resetButton                     matlab.ui.control.Button
        SmoothingwindowframesEditField  matlab.ui.control.NumericEditField
        SmoothingwindowframesEditFieldLabel  matlab.ui.control.Label
        ClearallButton                  matlab.ui.control.Button
        ComputesurfaceButton            matlab.ui.control.Button
        SelectboxButton                 matlab.ui.control.Button
        BoxesperlineEditField           matlab.ui.control.NumericEditField
        BoxesperlineEditFieldLabel      matlab.ui.control.Label
        surfAxes                        matlab.ui.control.UIAxes
        peaksAxes                       matlab.ui.control.UIAxes
        projAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Nframes % number of frames
        w = 0;%width
        h = 0; %height
        xy_res = 1;% microns per pixel
        z_res = 1; %microns per pixel
        chat_im; %full chat iamge stack
        dend_proj_im; %projection image of dendrites
        edges_x %divisions of image into boxes, rows
        edges_y %divisions of image into boxes, columns
        surf_lower %full surface (full resolution)
        surf_upper
        lower_pos %z frame of each lower surface box
        upper_pos
        curBox = 0; %current box index
        in_chat_click_loop = false;
    end
    
    methods (Access = private)
        
        function [fr1, fr2] = UIClickChat(app, rowIndex, colIndex)
            rangeX = app.edges_x(colIndex)+1:app.edges_x(colIndex+1);
            rangeY = app.edges_y(rowIndex)+1:app.edges_y(rowIndex+1);
            r = rectangle('Parent', app.projAxes, ...
                'Position', [rangeX(1), rangeY(1), rangeX(end)-rangeX(1), rangeY(end)-rangeY(1)], ...
                'EdgeColor', 'r','LineWidth', 2);

            v = squeeze(mean(mean(app.chat_im(rangeY, rangeX, :),2),1));
            v_smoothed = smooth(v,app.SmoothingwindowframesEditField.Value);
            L = length(v_smoothed);
            plot(app.peaksAxes,v_smoothed,'k-','LineWidth',1);
            hold(app.peaksAxes,'on');
            if ~isnan(app.lower_pos(rowIndex,colIndex))
                scatter(app.peaksAxes, app.lower_pos(rowIndex,colIndex), v_smoothed(app.lower_pos(rowIndex,colIndex)),'bx', 'LineWidth', 3);
            end
            if ~isnan(app.upper_pos(rowIndex,colIndex))
                scatter(app.peaksAxes, app.upper_pos(rowIndex,colIndex), v_smoothed(app.upper_pos(rowIndex,colIndex)),'bx', 'LineWidth', 3);
            end
            hold(app.peaksAxes,'off');

            %peak 1
            set(0, 'CurrentFigure', app.CHAT_analyzer);
            [px1, ~, key] = ginput(1);
            key = char(key);
            sprintf('px1 key: %s', key)
            if strcmp(key,'s')
                disp('skipping')
                px1 = nan;
            else
                px1 = round(px1);
                if ~isempty(px1)
                    if px1>0 && px1 < L
                        hold(app.peaksAxes, 'on');
                        scatter(app.peaksAxes, px1, v_smoothed(px1), 'rx', 'LineWidth', 3);
                        hold(app.peaksAxes, 'off');
                    end
                end
            end
            
            %peak 2
            set(0, 'CurrentFigure', app.CHAT_analyzer);
            [px2, ~, key] = ginput(1);
            key = char(key);
            sprintf('px2 key: %s ', key)
            if strcmp(key,'s')
                px2 = nan;
            else
                px2 = round(px2);
                if ~isempty(px2)
                    if px2>0 && px2 < L
                        hold(app.peaksAxes, 'on');
                        scatter(app.peaksAxes, px2, v_smoothed(px2),'rx', 'LineWidth', 3);
                        hold(app.peaksAxes, 'off');
                    end
                end
            end
            
            %px1
            %px2
            try
                sorted_peaks = sort([px1, px2]);
                fr1 = sorted_peaks(1);
                fr2 = sorted_peaks(2);
                if isnan(fr1) || isnan(fr2)
                    set(r,'EdgeColor','b');                    
                else
                    set(r,'EdgeColor','g');
                end
            catch
                fr1 = nan;
                fr2 = nan;
                set(r,'EdgeColor','b');
            end
                
        end
        
        function chat_click_loop(app)           
           app.in_chat_click_loop = true;
        end
        
        function updateSurface(app)
            set(app.CHAT_analyzer, 'Name', 'calculating surface ...');  
            %remove outliers
            m = nanmedian(app.lower_pos(:));
            sd = nanstd(app.lower_pos(:));
            app.lower_pos(app.lower_pos>m+3*sd) = nan;
            app.lower_pos(app.lower_pos<m-3*sd) = nan;
            m = nanmedian(app.upper_pos(:));
            sd = nanstd(app.upper_pos(:));
            app.upper_pos(app.upper_pos>m+3*sd) = nan;
            app.upper_pos(app.upper_pos<m-3*sd) = nan;
            
            lower = inpaint_nans(app.lower_pos);
            upper = inpaint_nans(app.upper_pos);
            app.surf_lower = imresize(lower, [app.w, app.h]) .* app.z_res;
            app.surf_upper = imresize(upper, [app.w, app.h]) .* app.z_res;
            set(app.CHAT_analyzer,'Name','Chat analyzer');
            app.plotSurface();
        end

        function plotSurface(app)
            set(app.CHAT_analyzer, 'Name', 'Plotting surface ...');  
            %drawing
            [x,y] = meshgrid(1:app.h, 1:app.w);
            x = x * app.xy_res;
            y = y * app.xy_res;
            
            cmin = min(min(app.surf_lower,[],'all'), min(app.surf_upper,[],'all'));
            cmax = max(max(app.surf_lower,[],'all'), max(app.surf_upper,[],'all'));
            
            surf(app.surfAxes,x,y,app.surf_lower,'edgecolor','none','FaceAlpha',0.5);
            hold(app.surfAxes, 'on');
            surf(app.surfAxes,x,y,app.surf_upper,'edgecolor','none','FaceAlpha',0.5);
            
%             dend_color = imresize(app.dend_proj_im', [app.h, app.w]);
            dend_color = app.dend_proj_im;
            dend_color = dend_color - median(dend_color,'all');
            dend_color(dend_color < 0) = 0;
            dend_color = dend_color ./ max(dend_color,[],'all') * (cmax-cmin) + cmin;
            surf(app.surfAxes,x, y,zeros(size(x)), dend_color','edgecolor','none');
            
            axis(app.surfAxes, 'vis3d');
            hold(app.surfAxes, 'off');

            %view(app.surfAxes, 90, 50);
            set(app.CHAT_analyzer,'Name','Chat analyzer');            
        end
        
        function load_chat_image(app, fname_full)
            set(app.CHAT_analyzer,'Name','Loading image...');
            try
                info = imfinfo(fname_full);
                app.Nframes = length(info);
                app.w = info(1).Width;
                app.h = info(1).Height;
                app.xy_res = 1./info(1).XResolution;
                desc = info(1).ImageDescription;
                try
                    a = strsplit(desc,'spacing=');
                    app.z_res = str2double(strtok(a{2}));
                catch
                    disp('z spacing not found in file');
                end
                app.chat_im = int32(zeros(app.h, app.w, app.Nframes));

                for i=1:app.Nframes
                    app.chat_im(:,:,i) = imread(fname_full, i);
                end

                %use current boxes number to divide image and set enable to false
                app.edges_x = round(linspace(0,app.w,app.BoxesperlineEditField.Value+1));
                app.edges_y = round(linspace(0,app.h,app.BoxesperlineEditField.Value+1));
                set(app.BoxesperlineEditField,'Editable',false);

                %initialize positions
                app.lower_pos = nan(app.BoxesperlineEditField.Value, app.BoxesperlineEditField.Value);
                app.upper_pos = nan(app.BoxesperlineEditField.Value, app.BoxesperlineEditField.Value);

                %start loop
                app.curBox = 0;
                app.chat_click_loop();
            catch ME
                fprintf('Chat image load error: %s\n', ME.message);
            end
            set(app.CHAT_analyzer,'Name','Chat analyzer');
        end
        
        function load_cell_image(app, fname_full)
            if app.w==0
                disp('ChAT image must be loaded first');
                return;
            end

            try
                set(app.CHAT_analyzer,'Name','Loading image...');
                im = int32(zeros(app.h, app.w, app.Nframes));

                for i=1:app.Nframes
                    im(:,:,i) = imread(fname_full, i);
                end
                app.dend_proj_im = squeeze(max(im,[],3));
                hold(app.projAxes,'off');
                imagesc(app.projAxes,app.dend_proj_im);
                axis(app.projAxes,'tight');
                hold(app.projAxes,'on');
            catch ME
                fprintf('Cell image load error: %s\n', ME.message);
            end
            set(app.CHAT_analyzer,'Name','Chat analyzer');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, folder_select)
            if nargin > 1
                if folder_select
                    path = uigetdir([],'Load image files from folder...');
                    if ~isempty(path)
                       C = strsplit(path,filesep);
                       cellname = C{end};
                       fname_chat = sprintf('%s_chat.tif',cellname);
                       fname_cell = sprintf('%s_cell.tif',cellname);
                       app.load_chat_image([path filesep fname_chat]);
                       app.load_cell_image([path filesep fname_cell]);
                    end
                end
            end

        end

        % Menu selected function: LoadChATimageMenu
        function LoadChATimageMenuSelected(app, event)
            [fname, fpath] = uigetfile('*.tif*','Select ChAT image');            
            if fname
                app.load_chat_image([fpath filesep fname]);
            end
        end

        % Menu selected function: LoaddendritesimageMenu
        function LoaddendritesimageMenuSelected(app, event)
            [fname, fpath] = uigetfile('*.tif*','Select dendrites image');
            app.load_cell_image([fpath filesep fname]);                        
        end

        % Close request function: CHAT_analyzer
        function CHAT_analyzerCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.CHAT_analyzerCloseRequest(event);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            set(app.BoxesperlineEditField, 'Editable', true);
            app.surf_lower = [];
            app.surf_upper = [];
            imagesc(app.projAxes,app.dend_proj_im);
        end

        % Key press function: CHAT_analyzer
        function CHAT_analyzerKeyPress(app, event)
            key = event.Key;
            sprintf('CHAT_analyzerKeyPress: %s ', key)
            app.in_chat_click_loop
            if app.in_chat_click_loop
                Nbox = app.BoxesperlineEditField.Value;
                switch key
                    case {'rightarrow', 'return'}
                        app.in_chat_click_loop = true;
                        app.curBox
                        ind = app.curBox+1;
                        [x,y] = ind2sub([Nbox, Nbox],ind)
                        if ind < Nbox^2
                            while ind < Nbox^2 && ~isnan(app.lower_pos(x,y))
                                ind=ind+1;
                                if ind>Nbox^2
                                    break;
                                else
                                    [x,y] = ind2sub([Nbox, Nbox],ind)
                                end
                            end
                        end
                        if isnan(app.lower_pos(x,y))
                            app.curBox = ind;
                            sprintf('curBox: %d', app.curBox)
                            [app.lower_pos(x,y), app.upper_pos(x,y)] = app.UIClickChat(y, x);
                        end
                    case 'escape'
                        app.in_chat_click_loop = false;
                    case 'leftarrow'
                        app.in_chat_click_loop = true;
                        ind = app.curBox-1;
                        [x,y] = ind2sub([Nbox, Nbox],ind);
                        if ind > 0
                            while ind > 0 && ~isnan(app.lower_pos(x,y))
                                ind=ind-1;
                                if ind<=0
                                    break;
                                else
                                    [x,y] = ind2sub([Nbox, Nbox],ind);
                                end
                            end
                        end
                        if isnan(app.lower_pos(x,y))
                            app.curBox = ind;
                            sprintf('curBox: %d', app.curBox)
                            [app.lower_pos(x,y), app.upper_pos(x,y)] = app.UIClickChat(y, x);
                        end
                end
            end
        end

        % Button pushed function: ComputesurfaceButton
        function ComputesurfaceButtonPushed(app, event)
            app.updateSurface();
        end

        % Menu selected function: SavesurfacesMenu
        function SavesurfacesMenuSelected(app, event)
            [fname, fpath] = uiputfile('*.mat','Save surfaces file','chat_surfaces.mat');
            
            if fname
                appdata.surfaces = {'chat_ON', 'chat_OFF'};
                appdata.surfaceMaps{1} = app.surf_lower ./ app.z_res; %??? TODO, fix this
                appdata.surfaceMaps{2} = app.surf_upper ./ app.z_res;
                
                save([fpath filesep fname], 'appdata');
            end

        end

        % Button pushed function: SelectboxButton
        function SelectboxButtonPushed(app, event)
            [x,y] = ginput(1)
            Nbox = length(app.edges_x) - 1;
            boxX = find(app.edges_x>x, 1 ) - 1;
            boxY = find(app.edges_y>y, 1 ) - 1;
            app.curBox = sub2ind([Nbox, Nbox],boxX,boxY); %perhaps XY in other order
            [app.lower_pos(boxY,boxX), app.upper_pos(boxY,boxX)] = app.UIClickChat(boxY, boxX)

            app.in_chat_click_loop = true;
        end

        % Button pushed function: AutodetectpeaksButton
        function AutodetectpeaksButtonPushed(app, event)
            app.in_chat_click_loop = false;
            Nbox = length(app.edges_x) - 1;
            app.curBox = 1;
            for i=1:Nbox^2
                app.curBox = i;
                sprintf('curBox: %d', app.curBox)
                [y,x] = ind2sub([Nbox, Nbox],app.curBox);

                rangeX = app.edges_x(x)+1:app.edges_x(x+1);
                rangeY = app.edges_y(y)+1:app.edges_y(y+1);
                v = squeeze(mean(mean(app.chat_im(rangeY, rangeX, :),2),1));

                v(v<1) = nan;            
                v_smoothed = smooth(v,app.SmoothingwindowframesEditField.Value);
                                
                v_range = range(v_smoothed);

                [~, peaks_loc] = findpeaks(v_smoothed, ...
                    'NPeaks', 2, ...
                    'MinPeakDistance', 3, ...
                    'MinPeakProminence', v_range/3);

                if length(peaks_loc) == 2
                    app.lower_pos(x,y) = peaks_loc(1);
                    app.upper_pos(x,y) = peaks_loc(2);
                end
            end
        end

        % Menu selected function: LoadsurfacesMenu
        function LoadsurfacesMenuSelected(app, event)
           [fname, fpath] = uigetfile('*.mat*','Select surfaces files');            
            if fname
                load([fpath filesep fname], 'appdata');
                app.surf_lower = appdata.surfaceMaps{1};
                app.surf_upper = appdata.surfaceMaps{2};
                app.lower_pos = round(app.surf_lower ./ app.z_res);
                app.upper_pos = round(app.surf_upper./ app.z_res);

                app.plotSurface();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CHAT_analyzer = uifigure('Visible', 'off');
            app.CHAT_analyzer.Name = 'Chat analyzer';
            app.CHAT_analyzer.CloseRequestFcn = createCallbackFcn(app, @CHAT_analyzerCloseRequest, true);
            app.CHAT_analyzer.KeyPressFcn = createCallbackFcn(app, @CHAT_analyzerKeyPress, true);
            app.FileMenu = uimenu(app.CHAT_analyzer);
            app.FileMenu.Text = 'File';
            app.LoadChATimageMenu = uimenu(app.FileMenu);
            app.LoadChATimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadChATimageMenuSelected, true);
            app.LoadChATimageMenu.Text = 'Load ChAT image';
            app.LoaddendritesimageMenu = uimenu(app.FileMenu);
            app.LoaddendritesimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddendritesimageMenuSelected, true);
            app.LoaddendritesimageMenu.Text = 'Load dendrites image';
            app.LoadsurfacesMenu = uimenu(app.FileMenu);
            app.LoadsurfacesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsurfacesMenuSelected, true);
            app.LoadsurfacesMenu.Text = 'Load surfaces';
            app.SavesurfacesMenu = uimenu(app.FileMenu);
            app.SavesurfacesMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesurfacesMenuSelected, true);
            app.SavesurfacesMenu.Text = 'Save surfaces';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.projAxes = uiaxes(app.CHAT_analyzer);
            title(app.projAxes, 'Z projection')
            zlabel(app.projAxes, 'Z')
            app.projAxes.XTick = [];
            app.projAxes.YTick = [];
            app.peaksAxes = uiaxes(app.CHAT_analyzer);
            title(app.peaksAxes, 'ChAT band picker')
            xlabel(app.peaksAxes, 'Depth (frames)')
            ylabel(app.peaksAxes, {'Intensity (a.u.)'; ''})
            zlabel(app.peaksAxes, 'Z')
            app.surfAxes = uiaxes(app.CHAT_analyzer);
            title(app.surfAxes, 'Surfaces')
            xlabel(app.surfAxes, 'μm')
            ylabel(app.surfAxes, 'μm')
            zlabel(app.surfAxes, 'μm')
            app.BoxesperlineEditFieldLabel = uilabel(app.CHAT_analyzer);
            app.BoxesperlineEditFieldLabel.Text = 'Boxes per line';
            app.BoxesperlineEditField = uieditfield(app.CHAT_analyzer, 'numeric');
            app.BoxesperlineEditField.Limits = [4 32];
            app.BoxesperlineEditField.RoundFractionalValues = 'on';
            app.BoxesperlineEditField.Value = 8;
            app.SelectboxButton = uibutton(app.CHAT_analyzer, 'push');
            app.SelectboxButton.ButtonPushedFcn = createCallbackFcn(app, @SelectboxButtonPushed, true);
            app.SelectboxButton.Text = 'Select box';
            app.ComputesurfaceButton = uibutton(app.CHAT_analyzer, 'push');
            app.ComputesurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ComputesurfaceButtonPushed, true);
            app.ComputesurfaceButton.Text = 'Compute surface';
            app.ClearallButton = uibutton(app.CHAT_analyzer, 'push');
            app.ClearallButton.Text = 'Clear all';
            app.SmoothingwindowframesEditFieldLabel = uilabel(app.CHAT_analyzer);
            app.SmoothingwindowframesEditFieldLabel.Text = 'Smoothing window (frames)';
            app.SmoothingwindowframesEditField = uieditfield(app.CHAT_analyzer, 'numeric');
            app.SmoothingwindowframesEditField.Limits = [1 15];
            app.SmoothingwindowframesEditField.RoundFractionalValues = 'on';
            app.SmoothingwindowframesEditField.Value = 3;
            app.resetButton = uibutton(app.CHAT_analyzer, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.AutodetectpeaksButton = uibutton(app.CHAT_analyzer, 'push');
            app.AutodetectpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AutodetectpeaksButtonPushed, true);
            app.AutodetectpeaksButton.Text = 'Auto detect peaks';
            % Show the figure after all components are created
            app.CHAT_analyzer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CHAT_analyzer_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CHAT_analyzer)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CHAT_analyzer)
        end
    end
end